classdef ConnectFour_ButtonsApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ClickHereforColumnTwoButton_2  matlab.ui.control.StateButton
        ClickHereforColumnSixButton    matlab.ui.control.StateButton
        ClickHereforColumnFiveButton   matlab.ui.control.StateButton
        ClickHereforColumnFourButton   matlab.ui.control.StateButton
        ClickHereforColumnThreeButton  matlab.ui.control.StateButton
        ClickHereforColumnTwoButton    matlab.ui.control.StateButton
        ClickHereforColumnOneButton    matlab.ui.control.StateButton
        Button_41                      matlab.ui.control.Button
        Button_40                      matlab.ui.control.Button
        Button_39                      matlab.ui.control.Button
        Button_38                      matlab.ui.control.Button
        Button_37                      matlab.ui.control.Button
        Button_36                      matlab.ui.control.Button
        Button_35                      matlab.ui.control.Button
        Button_34                      matlab.ui.control.Button
        Button_33                      matlab.ui.control.Button
        Button_32                      matlab.ui.control.Button
        Button_31                      matlab.ui.control.Button
        Button_30                      matlab.ui.control.Button
        Button_29                      matlab.ui.control.Button
        Button_28                      matlab.ui.control.Button
        Button_27                      matlab.ui.control.Button
        Button_26                      matlab.ui.control.Button
        Button_25                      matlab.ui.control.Button
        Button_24                      matlab.ui.control.Button
        Button_23                      matlab.ui.control.Button
        Button_42                      matlab.ui.control.Button
        Button_22                      matlab.ui.control.Button
        Button_21                      matlab.ui.control.Button
        Button_20                      matlab.ui.control.Button
        Button_19                      matlab.ui.control.Button
        Button_18                      matlab.ui.control.Button
        Button_17                      matlab.ui.control.Button
        Button_16                      matlab.ui.control.Button
        Button_15                      matlab.ui.control.Button
        Button_14                      matlab.ui.control.Button
        Button_13                      matlab.ui.control.Button
        Button_12                      matlab.ui.control.Button
        Button_11                      matlab.ui.control.Button
        Button_10                      matlab.ui.control.Button
        Button_9                       matlab.ui.control.Button
        Button_8                       matlab.ui.control.Button
        Button_7                       matlab.ui.control.Button
        Button_6                       matlab.ui.control.Button
        Button_5                       matlab.ui.control.Button
        Button_4                       matlab.ui.control.Button
        Button_3                       matlab.ui.control.Button
        Button_2                       matlab.ui.control.Button
        Button                         matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            
            if app.Button_6.BackgroundColor ~= [1 0 0] || app.Button_6.BackgroundColor ~= [1 1 0]
                app.Button_6.BackgroundColor = [1 0 0];
                
            elseif app.Button_6.BackgroundColor == [1 0 0] || app.Button_6.BackgroundColor == [1 1 0]
                    app.Button_5.BackgroundColor = [1 0 0];
            elseif app.Button_5.BackgroundColor == [1 0 0] || app.Button_5.BackgroundColor == [1 1 0]
                    app.Button_4.BackgroundColor = [1 0 0];
            elseif app.Button_4.BackgroundColor == [1 0 0] || app.Button_4.BackgroundColor == [1 1 0]
                    app.Button_3.BackgroundColor = [1 0 0];
            elseif app.Button_3.BackgroundColor == [1 0 0] || app.Button_3.BackgroundColor == [1 1 0]
                    app.Button_2.BackgroundColor = [1 0 0];
            elseif app.Button_2.BackgroundColor == [1 0 0] || app.Button_2.BackgroundColor == [1 1 0]
                    app.Button.BackgroundColor = [1 0 0];
            else app.Button.BackgroundColor == [1 0 0] || app.Button.BackgroundColor == [1 1 0]
                    disp('this column is full! please select another.')

            end

           % We do not intend to use this code in our project at all, but
           % this was a potential method that we had attempted before
           % deciding to use a different method.
           % -driver Ashley

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.Text = '';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.Text = '';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.Text = '';
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.Text = '';
            app.Button_11 = uibutton(app.UIFigure, 'push');
            app.Button_11.Text = '';
            app.Button_12 = uibutton(app.UIFigure, 'push');
            app.Button_12.Text = '';
            app.Button_13 = uibutton(app.UIFigure, 'push');
            app.Button_13.Text = '';
            app.Button_14 = uibutton(app.UIFigure, 'push');
            app.Button_14.Text = '';
            app.Button_15 = uibutton(app.UIFigure, 'push');
            app.Button_15.Text = '';
            app.Button_16 = uibutton(app.UIFigure, 'push');
            app.Button_16.Text = '';
            app.Button_17 = uibutton(app.UIFigure, 'push');
            app.Button_17.Text = '';
            app.Button_18 = uibutton(app.UIFigure, 'push');
            app.Button_18.Text = '';
            app.Button_19 = uibutton(app.UIFigure, 'push');
            app.Button_19.Text = '';
            app.Button_20 = uibutton(app.UIFigure, 'push');
            app.Button_20.Text = '';
            app.Button_21 = uibutton(app.UIFigure, 'push');
            app.Button_21.Text = '';
            app.Button_22 = uibutton(app.UIFigure, 'push');
            app.Button_22.Text = '';
            app.Button_42 = uibutton(app.UIFigure, 'push');
            app.Button_42.Text = '';
            app.Button_23 = uibutton(app.UIFigure, 'push');
            app.Button_23.Text = '';
            app.Button_24 = uibutton(app.UIFigure, 'push');
            app.Button_24.Text = '';
            app.Button_25 = uibutton(app.UIFigure, 'push');
            app.Button_25.Text = '';
            app.Button_26 = uibutton(app.UIFigure, 'push');
            app.Button_26.Text = '';
            app.Button_27 = uibutton(app.UIFigure, 'push');
            app.Button_27.Text = '';
            app.Button_28 = uibutton(app.UIFigure, 'push');
            app.Button_28.Text = '';
            app.Button_29 = uibutton(app.UIFigure, 'push');
            app.Button_29.Text = '';
            app.Button_30 = uibutton(app.UIFigure, 'push');
            app.Button_30.Text = '';
            app.Button_31 = uibutton(app.UIFigure, 'push');
            app.Button_31.Text = '';
            app.Button_32 = uibutton(app.UIFigure, 'push');
            app.Button_32.Text = '';
            app.Button_33 = uibutton(app.UIFigure, 'push');
            app.Button_33.Text = '';
            app.Button_34 = uibutton(app.UIFigure, 'push');
            app.Button_34.Text = '';
            app.Button_35 = uibutton(app.UIFigure, 'push');
            app.Button_35.Text = '';
            app.Button_36 = uibutton(app.UIFigure, 'push');
            app.Button_36.Text = '';
            app.Button_37 = uibutton(app.UIFigure, 'push');
            app.Button_37.Text = '';
            app.Button_38 = uibutton(app.UIFigure, 'push');
            app.Button_38.Text = '';
            app.Button_39 = uibutton(app.UIFigure, 'push');
            app.Button_39.Text = '';
            app.Button_40 = uibutton(app.UIFigure, 'push');
            app.Button_40.Text = '';
            app.Button_41 = uibutton(app.UIFigure, 'push');
            app.Button_41.Text = '';
            app.ClickHereforColumnOneButton = uibutton(app.UIFigure, 'state');
            app.ClickHereforColumnOneButton.Text = 'Click Here for Column One';
            app.ClickHereforColumnTwoButton = uibutton(app.UIFigure, 'state');
            app.ClickHereforColumnTwoButton.Text = 'Click Here for Column Two';
            app.ClickHereforColumnThreeButton = uibutton(app.UIFigure, 'state');
            app.ClickHereforColumnThreeButton.Text = 'Click Here for Column Three';
            app.ClickHereforColumnFourButton = uibutton(app.UIFigure, 'state');
            app.ClickHereforColumnFourButton.Text = 'Click Here for Column Four';
            app.ClickHereforColumnFiveButton = uibutton(app.UIFigure, 'state');
            app.ClickHereforColumnFiveButton.Text = 'Click Here for Column Five';
            app.ClickHereforColumnSixButton = uibutton(app.UIFigure, 'state');
            app.ClickHereforColumnSixButton.Text = 'Click Here for Column Six';
            app.ClickHereforColumnTwoButton_2 = uibutton(app.UIFigure, 'state');
            app.ClickHereforColumnTwoButton_2.Text = 'Click Here for Column Two';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConnectFour_ButtonsApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end